classdef Lab2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        TextArea_2                   matlab.ui.control.TextArea
        Image11                      matlab.ui.control.Image
        Image10                      matlab.ui.control.Image
        Image9                       matlab.ui.control.Image
        WhatsinstockButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StartheretogettheShelfElfbottoprepareyoureverydaymealLabel  matlab.ui.control.Label
        GETSTARTEDButton             matlab.ui.control.StateButton
        EstopSwitch                  matlab.ui.control.Switch
        EstopSwitchLabel             matlab.ui.control.Label
        SeeallMealsButton            matlab.ui.control.Button
        MyprofileButton              matlab.ui.control.Button
        Image13                      matlab.ui.control.Image
        OrderTab                     matlab.ui.container.Tab
        HomePageButton               matlab.ui.control.StateButton
        GalleryButton_2              matlab.ui.control.StateButton
        CategoriesPanel_2            matlab.ui.container.Panel
        ConfirmOrderButton           matlab.ui.control.StateButton
        Image18                      matlab.ui.control.Image
        Image17                      matlab.ui.control.Image
        Image16                      matlab.ui.control.Image
        MealsoftheDayListBox_2       matlab.ui.control.ListBox
        MealsoftheDayListBox_2Label  matlab.ui.control.Label
        ProfileInformationPanel      matlab.ui.container.Panel
        Image15                      matlab.ui.control.Image
        Image14                      matlab.ui.control.Image
        ListBox                      matlab.ui.control.ListBox
        OrderPerferencesPanel        matlab.ui.container.Panel
        SelectListBox                matlab.ui.control.ListBox
        SelectListBoxLabel           matlab.ui.control.Label
        GalleryTab                   matlab.ui.container.Tab
        WhatsinStockLabel            matlab.ui.control.Label
        DessertButton                matlab.ui.control.StateButton
        SnacksButton                 matlab.ui.control.StateButton
        DrinksButton                 matlab.ui.control.StateButton
        DinnerButton                 matlab.ui.control.StateButton
        LunchButton                  matlab.ui.control.StateButton
        BreakfastButton              matlab.ui.control.StateButton
        OrderPageButton              matlab.ui.control.StateButton
        SearchPageButton             matlab.ui.control.StateButton
        Image6                       matlab.ui.control.Image
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        SearchTab                    matlab.ui.container.Tab
        TextArea_3                   matlab.ui.control.TextArea
        Hyperlink3                   matlab.ui.control.Hyperlink
        Image19                      matlab.ui.control.Image
        GalleryButton                matlab.ui.control.StateButton
        TextArea                     matlab.ui.control.TextArea
        Hyperlink2                   matlab.ui.control.Hyperlink
        Image12                      matlab.ui.control.Image
        Tab                          matlab.ui.container.Tab
        zButton_2                    matlab.ui.control.StateButton
        zButton                      matlab.ui.control.StateButton
        yButton_2                    matlab.ui.control.StateButton
        yButton                      matlab.ui.control.StateButton
        xButton_2                    matlab.ui.control.StateButton
        xButton                      matlab.ui.control.StateButton
        PlotrobotButton              matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = public)
    lab2Assignment %Instance for Lab2_Assignment class file
    EStopStatus  = 'Run' % Instance of Estop
    state
    end

    methods
        function self = Lab2GUI
            % Create an instance of Lab2_GUI
            self.lab2Assignment = Lab2_AssignmentUpdated();
        end
    end

    methods (Access = public)
        % Initialisation method
        function initialise(app, lab2Assignment)
            app.lab2Assignment = lab2Assignment;

            % Register the callback for the E-stop switch
            app.EstopSwitch.ValueChangedFcn = @(src, event)app.EstopSwitchValueChanged(src, event);
        end
        
        function initialiseRobots(self)
            % Initialise robot instances
           % self.robot1 = LinearUR10(baseTr);
            self.robot = Robo();
        end

        function startRobot(app)
            try
                % app.robot.start();
                app.EStopStatus = 'Run';
                disp('Robot started');
            catch exception
                % Handle any exceptions that may occur
                disp(['Error starting the robot: ' exception.message]);
            end
        end

        % Method to stop the robot
        function stopRobot(app)
            try
                % Code to stop the robot goes here
                % app.robot.stop();
                app.EStopStatus = 'Stop';
                disp('Robot stopped');
            catch exception
                % Handle any exceptions that may occur
                disp(['Error stopping the robot: ' exception.message]);
            end
        end

         function state = getSwitchStatus(app)
        % This function should return the current status of the E-stop switch.
        % The implementation will depend on how the E-stop switch's status is retrieved.
        state = app.EStopStatus; % Example implementation if status is stored in a property
         end


        % Helper function to move the robot in the x-direction
        function moveRobotInXDirection(app, distance)
            % Check if the robot is already plotted
            if isfield(app, 'roboRobot')
                %roboRobot = app.roboRobot;
                % Get the current robot position
                currentQ = roboRobot.model.getpos;
        
                % Calculate the new position by adding or subtracting the specified distance
                newQ = currentQ;
                newQ(1) = newQ(1) + distance;

                % Animate the robot to the new position
                app.roboRobot.model.animate(newQ, 'delay', 0.01, 'target', app.UIAxes);  % Specify the target axes as app.UIAxes
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MyprofileButton
        function MyprofileButtonPushed(app, event)
            % Switch to the "Order" tab
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Button pushed function: WhatsinstockButton
        function WhatsinstockButtonPushed(app, event)
            % Switch to the "Order" tab
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Button pushed function: SeeallMealsButton
        function SeeallMealsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Value changed function: GETSTARTEDButton
        function GETSTARTEDButtonValueChanged(app, event)
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Value changed function: GalleryButton
        function GalleryButtonValueChanged(app, event)
            value = app.GalleryButton.Value;
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Value changed function: OrderPageButton
        function OrderPageButtonValueChanged(app, event)
            value = app.OrderPageButton.Value;
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Value changed function: SearchPageButton
        function SearchPageButtonValueChanged(app, event)
            value = app.SearchPageButton.Value;
            app.TabGroup.SelectedTab = app.SearchTab;
        end

        % Value changed function: GalleryButton_2
        function GalleryButton_2ValueChanged(app, event)
            value = app.GalleryButton_2.Value;
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Value changed function: EstopSwitch
        function EstopSwitchValueChanged(app, event)
            value = app.EstopSwitch.Value;
            if strcmp(value, 'Run')
                % Code to start the robot goes here
                app.startRobot();
            elseif strcmp(value, 'Stop')
                % Code to stop the robot goes here
                app.stopRobot();
            else
                 disp('Invalid E-stop switch value');
            end
            
            app.lab2Assignment.toggleEstop(app.EstopStatus);  % Call the updated toggleEstop method

        end

        % Value changed function: HomePageButton
        function HomePageButtonValueChanged(app, event)
            value = app.HomePageButton.Value;
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Value changed function: ConfirmOrderButton
        function ConfirmOrderButtonValueChanged(app, event)
            %value = app.ConfirmOrderButton.Value;
            app.Lab2_AssignmentAngie.objectLocater();  % Call the objectLocater method
        end

        % Value changed function: PlotrobotButton
        function PlotrobotButtonValueChanged(app, event)
            value = app.PlotrobotButton.Value;
                 if value
                    % Button is pressed, so create and plot the Robo robot
                    baseTr = transl(0, 0, 0);  % You can adjust the base transformation as needed
                    roboRobot = Robo(baseTr);

                    % Plot the robot in the UIAxes of the app
                    app.UIAxes.Parent = app.UIFigure;  % Ensure the UIAxes is associated with the app's figure
                    roboRobot.model.plot3d(zeros(1, roboRobot.model.n), 'noarrow', 'workspace', [-2 2 -2 2 -0.3 2]);
                    
                    % Set appropriate axes properties if needed
                    view(app.UIAxes, 3);  % Set the view to 3D
                    axis(app.UIAxes, 'equal');  % Ensure equal aspect ratio
                    grid(app.UIAxes, 'on');  % Show grid
                else
                    % Button is released, clear the plot
                    %cla(app.UIAxes);
                end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image13 = uiimage(app.HomeTab);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'Images', 'HomeTabBackground.jpeg');
            app.MyprofileButton = uibutton(app.HomeTab, 'push');
            app.MyprofileButton.ButtonPushedFcn = createCallbackFcn(app, @MyprofileButtonPushed, true);
            app.MyprofileButton.Text = 'My profile';
            app.SeeallMealsButton = uibutton(app.HomeTab, 'push');
            app.SeeallMealsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeallMealsButtonPushed, true);
            app.SeeallMealsButton.Text = 'See all Meals';
            app.EstopSwitchLabel = uilabel(app.HomeTab);
            app.EstopSwitchLabel.Text = 'E-stop';
            app.EstopSwitch = uiswitch(app.HomeTab, 'slider');
            app.EstopSwitch.Items = {'Run', 'Stop'};
            app.EstopSwitch.ValueChangedFcn = createCallbackFcn(app, @EstopSwitchValueChanged, true);
            app.EstopSwitch.Value = 'Run';
            app.GETSTARTEDButton = uibutton(app.HomeTab, 'state');
            app.GETSTARTEDButton.ValueChangedFcn = createCallbackFcn(app, @GETSTARTEDButtonValueChanged, true);
            app.GETSTARTEDButton.Text = 'GET STARTED';
            app.StartheretogettheShelfElfbottoprepareyoureverydaymealLabel = uilabel(app.HomeTab);
            app.StartheretogettheShelfElfbottoprepareyoureverydaymealLabel.Text = 'Begin your culinary journey with ShelfElf, your personalised meal preparation bot';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = 'Let ShelfElf prep your meals for the day';
            app.WhatsinstockButton = uibutton(app.HomeTab, 'push');
            app.WhatsinstockButton.ButtonPushedFcn = createCallbackFcn(app, @WhatsinstockButtonPushed, true);
            app.WhatsinstockButton.Text = 'What''s in stock';
            app.Image9 = uiimage(app.HomeTab);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'Images', 'Icon store matcha.jpeg');
            app.Image10 = uiimage(app.HomeTab);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'Images', 'Sage Green Cat icon.jpeg');
            app.Image11 = uiimage(app.HomeTab);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'Images', 'ProfileImage.jpeg');
            app.TextArea_2 = uitextarea(app.HomeTab);
            app.TextArea_2.Value = {'Functional Emergency Stop for Robot Operation'};
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.ProfileInformationPanel = uipanel(app.OrderTab);
            app.ProfileInformationPanel.Title = 'Profile Information';
            app.OrderPerferencesPanel = uipanel(app.ProfileInformationPanel);
            app.OrderPerferencesPanel.Title = 'Order Perferences';
            app.SelectListBoxLabel = uilabel(app.OrderPerferencesPanel);
            app.SelectListBoxLabel.Text = 'Select';
            app.SelectListBox = uilistbox(app.OrderPerferencesPanel);
            app.SelectListBox.Items = {'Order history', 'Favourites', 'Recommendations'};
            app.SelectListBox.Value = 'Order history';
            app.ListBox = uilistbox(app.ProfileInformationPanel);
            app.ListBox.Items = {'Account Info', 'Delivery Address', 'Inbox'};
            app.ListBox.Value = 'Account Info';
            app.Image14 = uiimage(app.ProfileInformationPanel);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'Images', 'Profile2.jpeg');
            app.Image15 = uiimage(app.ProfileInformationPanel);
            app.Image15.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon.jpeg');
            app.CategoriesPanel_2 = uipanel(app.OrderTab);
            app.CategoriesPanel_2.Title = 'Categories';
            app.MealsoftheDayListBox_2Label = uilabel(app.CategoriesPanel_2);
            app.MealsoftheDayListBox_2Label.Text = 'Meals of the Day';
            app.MealsoftheDayListBox_2 = uilistbox(app.CategoriesPanel_2);
            app.MealsoftheDayListBox_2.Items = {'Breakfast', 'Lunch', 'Dinner', 'Drinks', 'Snack', 'Dessert'};
            app.MealsoftheDayListBox_2.Value = 'Breakfast';
            app.Image16 = uiimage(app.CategoriesPanel_2);
            app.Image16.ImageSource = fullfile(pathToMLAPP, 'Images', 'CutleryIcon.jpeg');
            app.Image17 = uiimage(app.CategoriesPanel_2);
            app.Image17.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon8.png');
            app.Image18 = uiimage(app.CategoriesPanel_2);
            app.Image18.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon7.png');
            app.ConfirmOrderButton = uibutton(app.CategoriesPanel_2, 'state');
            app.ConfirmOrderButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmOrderButtonValueChanged, true);
            app.ConfirmOrderButton.Text = 'Confirm Order';
            app.GalleryButton_2 = uibutton(app.OrderTab, 'state');
            app.GalleryButton_2.ValueChangedFcn = createCallbackFcn(app, @GalleryButton_2ValueChanged, true);
            app.GalleryButton_2.Text = 'Gallery ->';
            app.HomePageButton = uibutton(app.OrderTab, 'state');
            app.HomePageButton.ValueChangedFcn = createCallbackFcn(app, @HomePageButtonValueChanged, true);
            app.HomePageButton.Text = '<- Home Page';
            app.GalleryTab = uitab(app.TabGroup);
            app.GalleryTab.Title = 'Gallery';
            app.Image = uiimage(app.GalleryTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon1.png');
            app.Image2 = uiimage(app.GalleryTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon2.png');
            app.Image3 = uiimage(app.GalleryTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon3.png');
            app.Image4 = uiimage(app.GalleryTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon4.png');
            app.Image5 = uiimage(app.GalleryTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon5.png');
            app.Image6 = uiimage(app.GalleryTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Images', 'icon6.png');
            app.SearchPageButton = uibutton(app.GalleryTab, 'state');
            app.SearchPageButton.ValueChangedFcn = createCallbackFcn(app, @SearchPageButtonValueChanged, true);
            app.SearchPageButton.Text = 'Search Page ->';
            app.OrderPageButton = uibutton(app.GalleryTab, 'state');
            app.OrderPageButton.ValueChangedFcn = createCallbackFcn(app, @OrderPageButtonValueChanged, true);
            app.OrderPageButton.Text = '<- Order Page';
            app.BreakfastButton = uibutton(app.GalleryTab, 'state');
            app.BreakfastButton.Text = 'Breakfast';
            app.LunchButton = uibutton(app.GalleryTab, 'state');
            app.LunchButton.Text = 'Lunch';
            app.DinnerButton = uibutton(app.GalleryTab, 'state');
            app.DinnerButton.Text = 'Dinner';
            app.DrinksButton = uibutton(app.GalleryTab, 'state');
            app.DrinksButton.Text = 'Drinks';
            app.SnacksButton = uibutton(app.GalleryTab, 'state');
            app.SnacksButton.Text = 'Snacks';
            app.DessertButton = uibutton(app.GalleryTab, 'state');
            app.DessertButton.Text = 'Dessert';
            app.WhatsinStockLabel = uilabel(app.GalleryTab);
            app.WhatsinStockLabel.Text = 'What''s in Stock';
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.Image12 = uiimage(app.SearchTab);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'Images', 'SearchTabBackground.jpeg');
            app.Hyperlink2 = uihyperlink(app.SearchTab);
            app.Hyperlink2.URL = 'https://www.woolworths.com.au/?utm_source=google&utm_medium=cpc&utm_campaign=WW-0001&cq_net=g&cq_src=GOOGLE&cq_cmp=Woolies_8458_BAU_Brand_Exact_Head%20Term%20Pure_WW-0001&cq_med=71700000071994282&cq_plac=&cq_term=woolworths&ds_adt=&cq_plt=gp&cq_gclid=CjwKCAjw7c2pBhAZEiwA88pOF9TqWBwI3q790H22A4HGZWsAoFJjKowIudiV3FWZ1Op-FpNmKClg7BoCyTQQAvD_BwE&ds_de=c&ds_pc=&ds_cr=528130855897&ds_tid=aud-848956741194:kwd-14367402&ds_locphys=9071713&ds_pid=&cmpid=smsm:ds:GOOGLE:Woolies_8458_BAU_Brand_Exact_Head%20Term%20Pure_WW-0001:woolworths&gclid=CjwKCAjw7c2pBhAZEiwA88pOF9TqWBwI3q790H22A4HGZWsAoFJjKowIudiV3FWZ1Op-FpNmKClg7BoCyTQQAvD_BwE&gclsrc=aw.ds';
            app.Hyperlink2.Text = 'Supermarket';
            app.TextArea = uitextarea(app.SearchTab);
            app.TextArea.Value = {'Place an order for additonal groceries from your nearby supermarket'};
            app.GalleryButton = uibutton(app.SearchTab, 'state');
            app.GalleryButton.ValueChangedFcn = createCallbackFcn(app, @GalleryButtonValueChanged, true);
            app.GalleryButton.Text = '<- Gallery';
            app.Image19 = uiimage(app.SearchTab);
            app.Image19.ImageSource = fullfile(pathToMLAPP, 'Images', 'PaleGreenBackground.png');
            app.Hyperlink3 = uihyperlink(app.SearchTab);
            app.Hyperlink3.URL = 'https://online-barcode-reader.inliteresearch.com/';
            app.Hyperlink3.Text = 'Bar Code Scanner';
            app.TextArea_3 = uitextarea(app.SearchTab);
            app.TextArea_3.Value = {'Click here to scan your products'};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotrobotButton = uibutton(app.Tab, 'state');
            app.PlotrobotButton.ValueChangedFcn = createCallbackFcn(app, @PlotrobotButtonValueChanged, true);
            app.PlotrobotButton.Text = 'Plot robot';
            app.xButton = uibutton(app.Tab, 'state');
            app.xButton.Text = 'x+';
            app.xButton_2 = uibutton(app.Tab, 'state');
            app.xButton_2.Text = 'x-';
            app.yButton = uibutton(app.Tab, 'state');
            app.yButton.Text = 'y+';
            app.yButton_2 = uibutton(app.Tab, 'state');
            app.yButton_2.Text = 'y-';
            app.zButton = uibutton(app.Tab, 'state');
            app.zButton.Text = 'z+';
            app.zButton_2 = uibutton(app.Tab, 'state');
            app.zButton_2.Text = 'z-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end